classdef simsound < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSTSUIFigure                 matlab.ui.Figure
        TrajectoryInterpolatedLamp   matlab.ui.control.Lamp
        TrajectoryInterpolatedLabel  matlab.ui.control.Label
        VIEWButton                   matlab.ui.control.Button
        STARTButton_3                matlab.ui.control.Button
        SpatialSoundSimulationLamp   matlab.ui.control.Lamp
        SpatialSoundSimulationLabel  matlab.ui.control.Label
        DEMOButton                   matlab.ui.control.Button
        STARTButton_2                matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        RoomTab                      matlab.ui.container.Tab
        PressureLabel                matlab.ui.control.Label
        HumidityLabel                matlab.ui.control.Label
        TemperatureLabel             matlab.ui.control.Label
        PressureEditField            matlab.ui.control.NumericEditField
        HumidityEditField            matlab.ui.control.NumericEditField
        TemperatureEditField         matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.EditField
        XEditField                   matlab.ui.control.EditField
        XEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.EditField
        YEditFieldLabel              matlab.ui.control.Label
        SAVEButton                   matlab.ui.control.Button
        DELETEButton_2               matlab.ui.control.Button
        UPDATEButton_2               matlab.ui.control.Button
        NEWButton_2                  matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        CustomButton                 matlab.ui.control.RadioButton
        RectangularButton            matlab.ui.control.RadioButton
        ListBox                      matlab.ui.control.ListBox
        ReceiverTab                  matlab.ui.container.Tab
        recLabel                     matlab.ui.control.Label
        PICKANEWLOCATIONButton       matlab.ui.control.Button
        azvalueLabel                 matlab.ui.control.Label
        AzimuthKnobLabel             matlab.ui.control.Label
        AzimuthKnob                  matlab.ui.control.Knob
        SourceTab                    matlab.ui.container.Tab
        EditField_2                  matlab.ui.control.EditField
        TRAJECTORYLabel              matlab.ui.control.Label
        SOUNDLabel                   matlab.ui.control.Label
        DELETEButton                 matlab.ui.control.Button
        UPDATEButton                 matlab.ui.control.Button
        timeLabel                    matlab.ui.control.Label
        freqLabel                    matlab.ui.control.Label
        SoundsampleDropDown          matlab.ui.control.DropDown
        NEWButton                    matlab.ui.control.Button
        TrajectoriesListBox          matlab.ui.control.ListBox
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updSndIdx(app)
            % Retrieve sound sample from list
            ddv = app.SoundsampleDropDown.Value;
            % Get directory for sound sample
            filedir = join(['soundfiles/generic/',ddv]);
            % Sound sample - audio info
            ddvi = audioinfo(filedir);
            % Update app sound labels
            app.freqLabel.Text = join([string(ddvi.SampleRate/1e3),'kHz']);
            app.timeLabel.Text = join([string(ddvi.Duration),'s']);
            % CLEAN
            clear ddv ddvi filedir
        end
        
        function initSndList(app)
            % Get generic sounds path
            if isunix
                sls = split(ls('soundfiles/generic/'));
            elseif ispc
                temp = ls('soundfiles/generic/');
                N=size(temp,1);
                sls = cell(N-2,1);
                for i=3:N
                    sls{i-2,1} = temp(i,:);
                end
                clear temp N;
            end
            % Initialize items cell
            sndItems = {};
            % Retrieve the items
            idx = 1;
            for i=1:size(sls,1)
                if sls{i} ~= ""
                    sndItems{1,idx} = sls{i};
                    idx = idx+1;
                end
            end
            % Update the sound dropdown list values
            app.SoundsampleDropDown.Items = sndItems;
            updSndIdx(app);
            % CLEAN
            clear sls sndItems idx;
        end
        
        function updTrajIdx(app)
            % Get items from trajectory folder
            if isunix
                ttd = split(ls('structures/trajectories'));
            elseif ispc
                temp = ls('structures/trajectories');
                N=size(temp,1);
                ttd = cell(N-2,1);
                for i=3:N
                    ttd{i-2,1} = temp(i,:);
                end
                clear temp N;
            end
            % Initialize items cell
            appItems = {};
            % Retrieve the items
            idx = 1;
            for i=1:length(ttd)
                if ttd{i} ~= ""
                    appItems{1,idx} = ttd{i};
                    idx = idx+1;
                end
            end
            % Update trajectory listbox values
            app.TrajectoriesListBox.Items = appItems;
            % update Lamps status
            updLamps(app);
            % CLEAN
            clear ttd idx appItems;
        end
        
        function delTrajEntry(app)
            % Retrieve trajectory value from listbox
            ttd = app.TrajectoriesListBox.Value;
            
            % In case of no trajectory selected
            if isempty(ttd)
                errormsg = 'Please select a trajectory.';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                clean errormsg;
                return;
            % In case of valid trajectory (selected)
            else
                % dialog box: confirmation for deleption
                selection = uiconfirm(  app.DSTSUIFigure,       ...
                                        'Delete trajectory?',   ...
                                        'Warning','Icon','warning');
                % user agreed to proceed
                if selection == "OK"
                    % get file path
                    filedir = join(['structures/trajectories/',ttd]);
                    % delete file
                    delete(filedir);
                    % update trajectory listbox values
                    updTrajIdx(app);
                    % update trajectory axis drawing
                    updTrajPlot(app);
                    % update Lamps status
                    updLamps(app);
                    % CLEAN NESTED VARs
                    clear filedir;
                end
            end
            
            % CLEAN
            clear ttd;
        end
        
        function updTrajPlot(app)
            % Retrieve trajectory value from listbox
            ttu = app.TrajectoriesListBox.Value;
            % Clean figure axes
            cla(app.UIAxes);
            % Update figure axes if there is any value selected
            if ~isempty(ttu)
                % Get directory for TRAJ structure
                filedir = join(['structures/trajectories/',ttu]);
                % TRAJ structure
                TRAJ = load(filedir);
                % ROOM path
                rpath = TRAJ.room.path;
                % ROOM structure
                ROOM = load(rpath);
                % Plot ROOM and RECEIVER
                loadRoomPlot(app,app.UIAxes,ROOM);
                % Plot handmade trajectory
                hold(app.UIAxes,'on')
                plot(app.UIAxes, TRAJ.data.x, TRAJ.data.y, 'b')
                hold(app.UIAxes,'off')
                % update Lamps status
                updLamps(app);
                % CLEAN LOOP VARs
                clear TRAJ ROOM rfolder rpath filedir value;
            end
            % CLEAN
            clear ttu;
        end
        
        function toggleRadioBtn(app)
            % Toggle buttons reserved for the "Rectangular" Room;
            app.XEditFieldLabel.Visible = ~app.XEditFieldLabel.Visible;
            app.XEditField.Visible = ~app.XEditField.Visible;
            app.XEditField.Enable = ~app.XEditField.Enable;
            app.YEditFieldLabel.Visible = ~app.YEditFieldLabel.Visible;
            app.YEditField.Visible = ~app.YEditField.Visible;
            app.YEditField.Enable = ~app.YEditField.Enable;
        end
        
        function toggleRoomEdit(app)
            % Toggle buttons reserved for the "NEW" Room;
            % Displays components that retrieve the user's
            % input for the desired room.
            
            % Toggle radio buttons if
            % "Rectangular" Room is selected
            if app.RectangularButton.Value == 1
                toggleRadioBtn(app);
            end
            % Retrieve ButtonGroup state
            % (if it's activated == Retrieval of info
            % for new room)
            state=app.ButtonGroup.Enable;
            
            
            if state=="on"  % turn off if state is "on"
                app.ButtonGroup.Enable = 'off';
                app.ButtonGroup.Visible = 'off';
                app.EditField.Enable = 'off';
                app.EditField.Visible = 'off';
                app.TemperatureLabel.Enable = 'off';
                app.TemperatureLabel.Visible = 'off';
                app.TemperatureEditField.Enable = 'off';
                app.TemperatureEditField.Visible = 'off';
                app.HumidityLabel.Enable = 'off';
                app.HumidityLabel.Visible = 'off';
                app.HumidityEditField.Enable = 'off';
                app.HumidityEditField.Visible = 'off';
                app.PressureLabel.Enable = 'off';
                app.PressureLabel.Visible = 'off';
                app.PressureEditField.Enable = 'off';
                app.PressureEditField.Visible = 'off';
                app.SAVEButton.Enable = 'off';
                app.SAVEButton.Visible = 'off';
            else            % turn on if state is "off"
                app.ButtonGroup.Enable = 'on';
                app.ButtonGroup.Visible = 'on';
                app.EditField.Enable = 'on';
                app.EditField.Visible = 'on';
                app.TemperatureLabel.Enable = 'on';
                app.TemperatureLabel.Visible = 'on';
                app.TemperatureEditField.Enable = 'on';
                app.TemperatureEditField.Visible = 'on';
                app.HumidityLabel.Enable = 'on';
                app.HumidityLabel.Visible = 'on';
                app.HumidityEditField.Enable = 'on';
                app.HumidityEditField.Visible = 'on';
                app.PressureLabel.Enable = 'on';
                app.PressureLabel.Visible = 'on';
                app.PressureEditField.Enable = 'on';
                app.PressureEditField.Visible = 'on';
                app.SAVEButton.Enable = 'on';
                app.SAVEButton.Visible = 'on';
            end
            
            % CLEAN
            clear state;
        end
        
        function saveRoom(app)
            % Retrieve room type
            RType = app.ButtonGroup.SelectedObject.Text;
            
            % Rectangular ROOM
            if      RType == "Rectangular"
                % Retrieve editfield values for X and Y
                Xval = app.XEditField.Value;
                Yval = app.YEditField.Value;
                
                % Check for empty values:
                % (exit function if a scenario is activated)
                % 1) Both fields are empty
                if isempty(Xval) && isempty(Yval)
                    errormsg = 'X field and Y field are empty.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                    clear errormsg;
                    return;
                else
                    % 2) X field is empty
                    if      isempty(Xval)
                        errormsg = 'X field is empty.';
                        uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                        clear errormsg;
                        return;
                    % 3) Y field is empty
                    elseif  isempty(Yval)
                        errormsg = 'Y field is empty.';
                        uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                        clear errormsg;
                        return;
                    end
                end
                
                % Convert string to numeric (double)
                Xval = str2double(Xval);
                Yval = str2double(Yval);
                
                % Throw error if values are not numeric
                if (isnan(Xval) || isnan(Yval))
                    errormsg = 'The room dimensions must not contain letters.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                    clear errormsg;
                    return;
                end
                
                % Check for negative/null values
                % (exit function if true)
                if (Xval<=0)  || (Yval<=0)
                    errormsg = 'The room dimensions must be positive.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                    clear errormsg;
                    return;
                end
                
                % save values into ROOM structure
                % and put z=3 (m) by default
                ROOM.xyz = [Xval Yval 3];
                
                % CLEAN NESTED VARs
                clear Xval Yval;
            % Custom ROOM
            elseif  RType == "Custom"
                errormsg = 'Option not available yet!';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                clear errormsg;
                return;
            end
            
            % Initialize receiver
            ROOM.rec.x = [];
            ROOM.rec.y = [];
            ROOM.rec.z = [];
            ROOM.rec.th = 0;
            ROOM.rec.phi = 0;
            
            % Values for reflection coefficients
            ROOM.rc=[0 0 0];
            
            % Retrieve and verify room environmental conditions
            ROOM.t = app.TemperatureEditField.Value;
            ROOM.h = app.HumidityEditField.Value;
            ROOM.p = app.PressureEditField.Value;
            if ((ROOM.t > 60) || (ROOM.t < -90))
                errormsg = 'Room temperature should be between [-90 60] ºC.';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                clear errormsg;
                return;
            elseif ((ROOM.h > 100) || (ROOM.h < 0))
                errormsg = 'Room humidity should be between [0 100] %.';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                clear errormsg;
                return;
            end
            
            % ROOM structure path
            path = 'structures/room/';
            
            % Retrieve ROOM name value from the listbox
            RName = app.EditField.Value;
            
            %__If nothing is written in the edittext
            %__box, generate path with a generic
            %__name plus a timestamp
            if isempty(RName)
                fname = join(['rectangle',datestr(now,'ddmmyy_HHMM_SS')]);
                fpath = join([path,fname]);
            %__Generate path with a custom name
            else
                % Throw error message if the file already exists
                if isfile(join([path,RName,'.mat']))
                    errormsg = sprintf('The room ''%s'' already exists!', RName);
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                    return;
                % Generate path
                else
                    fpath = join([path,RName]);
                end
            end
            
            % Save ROOM structure
            % with the specified path
            save(fpath,'-struct','ROOM');
            
            % Update ROOM listbox values
            updRoomIdx(app);
            
            % CLEAN
            clear ROOM RType RName path fname fpath;
        end
        
        function updRoomIdx(app)
            % Get directory for the ROOM structure
            if isunix
                rrd = split(ls('structures/room/'));
            elseif ispc
                temp = ls('structures/room');
                N=size(temp,1);
                rrd = cell(N-2,1);
                for i=3:N
                    rrd{i-2,1} = temp(i,:);
                end
                clear temp N;
            end
            % Initialize items cell
            appItems = {};
            % Retrieve the items
            idx = 1;
            for i=1:length(rrd)
                if rrd{i} ~= ""
                    appItems{1,idx} = rrd{i};
                    idx = idx+1;
                end
            end
            % Update ROOM listbox values
            app.ListBox.Items = appItems;
            % CLEAN
            clear rrd idx appItems;
        end
        
        function delRoomEntry(app)
            % Retrieve ROOM name value from listbox
            rrd = app.ListBox.Value;
            
            % Check if listbox selected value is empty
            if isempty(rrd)
                errormsg = 'Please select a room.';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                return;
            % Valid room is selected
            else
                % prompt user for confirmation
                selection = uiconfirm(  app.DSTSUIFigure,   ...
                                        'Delete room?',     ...
                                        'Warning','Icon','warning');
                % if user confirms to delete the file
                if selection == "OK"
                    % get ROOM file path
                    filedir = join(['structures/room/',rrd]);
                    % remove ROOM structure
                    delete(filedir);
                    % update ROOM listbox values
                    updRoomIdx(app);
                    % update axis drawing
                    updRoomPlot(app);
                    % CLEAN NESTED VARs
                    clear filedir;
                end
                
                % CLEAN NESTED VARs
                clear selection;
            end
            
            % CLEAN
            clear rrd;
        end
        
        function updRoomPlot(app)
            % Retrieve ROOM name value from listbox
            rrd = app.ListBox.Value;
            
            % Clean figure axes
            cla(app.UIAxes);
            
            % Draw the ROOM limits when the value
            % selected from the listbox is valid
            if ~isempty(rrd)
                % Get ROOM structure path
                filedir = join(['structures/room/',rrd]);
                % ROOM structure
                ROOM = load(filedir);
                
                % Draw ROOM limits
                rectangle(app.UIAxes,'Position', [0 0 ROOM.xyz(1:2)])
                drawnow
                
                % Adjust figure axis aspect ratio
                set(app.UIAxes,'DataAspectRatio',[1 1 1]);
                set(app.UIAxes,'PlotBoxAspectRatio',[ROOM.xyz(1:2) 1]);
                % Adjust figure axis limits
                set(app.UIAxes,'XLim', [0 ROOM.xyz(1)]);
                set(app.UIAxes,'YLim', [0 ROOM.xyz(2)]);
                % Update axis and figure
                drawnow
                
                % Prepare line for pointing the azimuth
                % (in case of receiver is not empty/ is defined)
                if ~isempty(ROOM.rec.x)
                    % prepare line
                    line.L = sqrt(ROOM.xyz(1).^2+ROOM.xyz(2).^2);
                    line.x = [  ROOM.rec.x ...
                                ROOM.rec.x+line.L*cosd(ROOM.rec.th)];
                    line.y = [  ROOM.rec.y ...
                                ROOM.rec.y+line.L*sind(ROOM.rec.th)];
                    % draw line on room
                    hold(app.UIAxes,'on')
                    plot(   app.UIAxes,               ...
                            ROOM.rec.x, ROOM.rec.y,   ...
                            'k+');
                    plot(   app.UIAxes,     ...
                            line.x, line.y, ...
                            'k--');
                    hold(app.UIAxes,'off')

                    % CLEAN NESTED VARs
                    clear line;
                end
             
                % CLEAN LOOP VARs
                clear ROOM filedir value;
            end
            
            % Reset app Lamps and trajectory listbox
            % when a ROOM listbox value is selected
            if ~isempty(app.TrajectoriesListBox.Value)
                set(app.TrajectoriesListBox, 'Value', {});
                updLamps(app);
            end
            
            % CLEAN
            clear rrd;
        end
        
        function updLamps(app)
            % Retrieve trajectory value from listbox
            ttd = app.TrajectoriesListBox.Value;
            
            % Check for TRAJ stats if the listbox
            % value is valid
            if ~isempty(ttd)
                % Get path for TRAJ structures
                filedir = join(['structures/trajectories/',ttd]);
                % TRAJ structure
                TRAJ = load(filedir);
                
                % Verify if the interpolated trajectory
                % path is valid;
                % Otherwise the stats are manually
                % put to 0 or null.
                if ~isfile(TRAJ.interp.path)
                    TRAJ.interp.sts=0;
                    TRAJ.interp.path='';
                end
                
                % Interpolated trajectory Lamp
                if TRAJ.interp.sts==1
                    app.TrajectoryInterpolatedLamp.Color = 'green';
                    app.TrajectoryInterpolatedLamp.Tag = '1';
                else
                    app.TrajectoryInterpolatedLamp.Color = 'red';
                    app.TrajectoryInterpolatedLamp.Tag = '0';
                end
                
                % Spatial sound Lamp
                if TRAJ.spatial.sts==1
                    app.SpatialSoundSimulationLamp.Color = 'green';
                    app.SpatialSoundSimulationLamp.Tag = '1';
                else
                    app.SpatialSoundSimulationLamp.Color = 'red';
                    app.SpatialSoundSimulationLamp.Tag = '0';
                end
                
                clear filedir TRAJ;
            else
            % Reset/Disable Lamps when the listbox value
            % is invalid/not selected
                app.TrajectoryInterpolatedLamp.Color = 'black';
                app.SpatialSoundSimulationLamp.Color = 'black';
                app.TrajectoryInterpolatedLamp.Tag = '-1';
                app.SpatialSoundSimulationLamp.Tag = '-1';
            end
            
            % CLEAN
            clear ttd;
        end
        
        
        function [score,missed] = checkFolder(~, dir, list)
            % Returns the score/hit score and the
            % missed items from the directory.
            % dir = directory
            % list = list of desired files
            
            % Initialize variables
            score = 0;      % score/hit
            marked = [];    % hit directories
            missed = [];    % missed directories
            
            % Search the directory and compare the
            % files to check if they match the "list"
            % files.
            for i=1:length(dir)
                for j=1:length(list)
                    if dir(i).name == list(j)
                        score = score + 1;
                        marked = [marked list(j)];
                    end
                end
            end
            
            % Search for missing files in case the
            % score does not match the list items length
            if score ~= length(list)
                if score == 0
                    missed = list;
                else
                    % finds elements missing in marked when comparing to
                    % list array
                    missed = setdiff(list,marked);
                end
            end
        end
        
        
        function updRoomEditFields(app)
            % updates editFields if "NEW" Room
            % button is pressed (editing is active)
            
            % ROOM structure
            ROOM = load(join(['structures/room/',app.ListBox.Value]));
            % update temperature, humidity and pressure fields
            app.TemperatureEditField.Value = ROOM.t;
            app.HumidityEditField.Value = ROOM.h;
            app.PressureEditField.Value = ROOM.p;
            
            % if the room is rectangular
            rectStatus = app.ButtonGroup.SelectedObject.Text;
            if rectStatus == "Rectangular"
                % update X and Y fields
                app.XEditField.Value = string(ROOM.xyz(1));
                app.YEditField.Value = string(ROOM.xyz(2));
            end
            
            % CLEAN
            clear editStatus ROOM rectStatus;
        end
        
        function updReceiver(app)
            % updates the knobs and values in
            % "Receiver" tab
            
            % ROOM structure
            ROOM = load(join(['structures/room/',app.ListBox.Value]));
            
            % update azimuth knob position and value
            app.AzimuthKnob.Value = ROOM.rec.th;
            
            % verify if the receiver exists
            % (one axis is enough to verify)
            RecStatus = ~isempty(ROOM.rec.x);
            if RecStatus
                % fundo verde com a localizaçao do receiver
                app.recLabel.Text = join(['(',num2str(round(ROOM.rec.x,2)),' ; ',num2str(round(ROOM.rec.y,2)),') m']);
                app.recLabel.BackgroundColor = [.47 .67 .19];
            else
                % fundo vermelho a indicar que nao ha receiver definido
                app.recLabel.Text = 'NO LOCATION';
                app.recLabel.BackgroundColor = [0.64 0.08 0.18];
            end
            
            % CLEAN
            clear ROOM RecStatus;
        end
        
        function loadRoomPlot(~, axes, ROOM)
            % similar to updRoomPlot, but the ROOM is given by
            % the input argument.
            % Use with caution, since there aren't asserts
            % or warnings of any kind.
            % ------------------------------------------------
            
            % Draw ROOM limits
            rectangle(axes,'Position', [0 0 ROOM.xyz(1:2)])
            drawnow
            
            % Adjust figure axis aspect ratio
            set(axes,'DataAspectRatio',[1 1 1]);
            set(axes,'PlotBoxAspectRatio',[ROOM.xyz(1:2) 1]);
            % Adjust figure axis limits
            set(axes,'XLim', [0 ROOM.xyz(1)]);
            set(axes,'YLim', [0 ROOM.xyz(2)]);
            % Update axis and figure
            drawnow
            
            % Prepare line for pointing the azimuth
            % (in case of receiver is not empty/ is defined)
            if ~isempty(ROOM.rec.x)
                % prepare line
                line.L = sqrt(ROOM.xyz(1).^2+ROOM.xyz(2).^2);
                line.x = [  ROOM.rec.x ...
                            ROOM.rec.x+line.L*cosd(ROOM.rec.th)];
                line.y = [  ROOM.rec.y ...
                            ROOM.rec.y+line.L*sind(ROOM.rec.th)];
                % draw line on room
                hold(axes,'on')
                plot(   axes,               ...
                        ROOM.rec.x, ROOM.rec.y,   ...
                        'k+');
                plot(   axes,     ...
                        line.x, line.y, ...
                        'k--');
                hold(axes,'off')

                % CLEAN NESTED VARs
                clear line;
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % cleanup commands
            evalin('base', 'clear')
            evalin('base', 'clc')
            evalin('base', 'close all')
            
            % retrieve main folder directory
            f.main = uigetdir('title','Select ''Localizador-Acustico'' folder:');
            f.dir = dir(f.main);
            
            % required folder and file names
            req.folders = ["functions","simLocUS"];
            req.functions = ...
            [
            "Receiver.m",      "addDivision.m",        "addReceiver.m",     ...
            "addReceiver0.m",	"addReceiver00.m",      "detect_az1.m",     ...
            "rotationMatrix.m"                                              ...
            ];
            
            req.simlocus = ...
            [
            "KemoL10_TF.m",     "Room.m",           "addSpk.m",         "makeFile.m",   ...
            "rotate2D.m",       "Kemo_L10.mat",     "SoundAtt.m",       "addWall.m",    ...
            "omni.m",           "Channel.m",        "Kemo_L10_RAD.mat", "addMic.m",     ...
            "displayRoom.m",    "impR.m",           "orthoNVec.m",                      ...
            ];
            
            % initializing score/matches
            score.folders = 0;
            score.functions = 0;
            score.simlocus = 0;
            
            % initializing missing folders or files arrays
            missing.folders = [];
            missing.functions = [];
            missing.simlocus = [];
            
            % check for folder names compatibility
            [score.folders, missing.folders] = checkFolder(app, f.dir, req.folders);
            
            % PROMPT FOR CORRECT FOLDER PATH _OR_ EXIT PROGRAM
            while score.folders ~= length(req.folders)
                f.msg = 'Folder not valid. Please choose the ''Localizador-Acustico'' folder. ';
                selection = uiconfirm(app.DSTSUIFigure, f.msg ,'Warning','Icon','error');
            
                if selection == "Cancel"
                    delete(app);
                    return;
                else
                    f.main = uigetdir('title','Select ''Localizador-Acustico'' folder:');
                    f.dir = dir(f.main);
                    [score.folders, missing.folders] = checkFolder(app, f.dir, req.folders);
                end
            end
            
            % change directory to main folder
            TO_DIR = sprintf('cd %s', f.main);
            evalin('base', TO_DIR);
            
            % retrieving contents of important files inside the main folder
            actual.functions = dir([f.main,'/functions']);
            actual.simlocus = dir([f.main,'/simLocUS']);
            
            % check for the necessary files
            % and fill the score and missing structures
            [score.functions, missing.functions] = checkFolder(app, actual.functions, req.functions);
            [score.simlocus, missing.simlocus] = checkFolder(app, actual.simlocus, req.simlocus);
            
            % ALERT THE USER FOR MISSING FILES AND AFTER THAT CLOSE PROGRAM
            if score.functions ~= length(req.functions)
                if score.simlocus ~= length(req.simlocus) % function and simlocus folders missing files
                    missing.msg = sprintf(...
                        'Missing files for ''SimLocUS'':\n %s.\n\nMissing files for ''functions'':\n%s.', ...
                        join(missing.simlocus,', '), join(missing.functions,', '));
                else % function folder missing files
                    missing.msg = sprintf(...
                        'Missing files for ''functions'':\n%s.', ...
                        join(missing.functions,', '));
                end
                selection = uiconfirm(app.DSTSUIFigure,missing.msg,'2D STS','Options',{'OK'},'Icon','error');
                if selection == "OK"
                    delete(app);
                    return;
                end
            elseif score.simlocus ~= length(req.simlocus) % simlocus folder missing files
                missing.msg = sprintf(...
                        'Missing files for ''SimLocUS'':\n %s.', ...
                        join(missing.simlocus,', '));
                selection = uiconfirm(app.DSTSUIFigure,missing.msg,'2D STS','Options',{'OK'},'Icon','error');
                if selection == "OK"
                    delete(app);
                    return;
                end
            end
            
            % include path commands
            evalin('base', 'addpath functions/')
            evalin('base', 'addpath simLocUS/')

            % Sound samples - dropdown bar init
            initSndList(app);
            
            % Division list init
            updRoomIdx(app);
            
            % Trajectory list init
            updTrajIdx(app);
            
            % Lamps init
            app.TrajectoryInterpolatedLamp.Color = 'black';
            app.SpatialSoundSimulationLamp.Color = 'black';
            
            % Turn off DivisionEdit
            toggleRoomEdit(app);
            
            % Initialize Receiver Location label
            app.recLabel.Text = 'NO LOCATION';
            
            % Initialize Azimuth label
            app.azvalueLabel.Text = '0';
            
            % Initialize Elevation label
%             app.elevvalueLabel.Text = '0';
            
            % Global variables init
            global trjF;        % drawing figure handle
            global DRAW_STS;    % draw status flag (trajectory)
            DRAW_STS=0;
            global REC_STS;     % picking new receiver flag
            REC_STS = 0;
            global VIEW_INTERP; % interplated trajectory window flag
            VIEW_INTERP = 0;
            global VIEW_SPATIAL;% spatial sound demo window flag
            VIEW_SPATIAL = 0;
        end

        % Value changed function: TrajectoriesListBox
        function TrajectoriesListBoxValueChanged(app, event)
            % update trajectory axis drawing + other elements
            updTrajPlot(app);
        end

        % Button pushed function: NEWButton
        function DRAWButton_Trajectory(app, event)
            % Verify if editbox name file already exists
            error = checkSave();
            if error
                return;
            end
            
            % Call global figure handle
            global trjF;
            
            % Call global draw status variable
            global DRAW_STS;
            % Detect if figure has been closed
            if(~ishandle(trjF))
                DRAW_STS = 0;
            end
            
            % Retrieve ROOM listbox name value
            rrd = app.ListBox.Value;
            % ROOM structure
            if ~isempty(rrd)
                ROOM = load(join(['structures/room/',rrd]));
            end
            
            % Initialize trajectory data arrays
            tX=[];
            tY=[];
            tZ=[];
            tV=[];

            % Initialize velocity variable (MMB callback)
            vel = 0.2; % velocity must be > 0
            
            % DRAW_STS
            % Indicates if drawing figure is open
            if DRAW_STS==0
                % invalid/no room selected
                if isempty(rrd)
                    uialert(app.DSTSUIFigure,'Invalid Room!','2D STS','Icon','error')
                % valid room selected
                else
                    % start drawing
                    DRAW_STS=1;
                    % initialize figure on global
                    % figure handle
                    trjF = figure();
                    trjF.ToolBar = 'none';
                    trjF.MenuBar = 'none';
                    trjF.Resize = 'off';
                    axes(trjF);
                    % add room details
                    loadRoomPlot(app,trjF.CurrentAxes,ROOM);
                    % add gui -> drawing interface
                    gui();
                end
            else
                uialert(app.DSTSUIFigure,'Finish the drawing / close the figure!','2D STS','Icon','error')
            end
            
            % FUNCTIONS:---------------------------------------------------
            % *************************************************************
            function gui()
                % initialize Mouse Click actuator
                % (left and right click)
                set(trjF, 'WindowButtonDownFcn', @actuator);
                % initialize mouse-scrollback callback
                set(trjF, 'windowscrollWheelFcn', @changeVel);
            end
            
            function actuator(~, ~)   
                % register mouse-click value
                mclick = get(trjF,'SelectionType');
                
                % init title
                titlemsg = sprintf('velocity = %.2f',vel);
                title(titlemsg);
                
                % attribute functions to LEFT/RIGHT
                % mouse clicks (LMB or RMB)
                if mclick == "normal"   % LMB
                    pDraw();            % draw point
                elseif mclick == "alt"  % RMB
                    endDraw();          % end drawing
                end
                
                % CLEAN FUNCTIONS VARs
                clear mclick titlemsg;
            end
            
            function pDraw()
                % get coordinates (x,y) for current
                % mouse position
                P = get (trjF.CurrentAxes, 'CurrentPoint');
                Px=P(1,1);
                Py=P(1,2);
                
                % if P is inside the rectangular
                % room limits, then accumulate data
                % and refresh the plot
                hold on;
                if (Px>=0) && (Px<=ROOM.xyz(1))
                    if (Py>=0) && (Py<=ROOM.xyz(2))
                        tX = [tX Px];
                        tY = [tY Py];
                        tZ = [tZ 1.5];
                        tV = [tV vel];
                        plot(tX, tY, 'k');
                    end
                end
                
                % CLEAN FUNCTION VARs
                clear P Px Py;
            end
            
            function endDraw()
                % disable Mouse Click actuator
                % (left and right click)
                set(trjF, 'WindowButtonDownFcn', []);
                % disable mouse-scrollback callback
                set(trjF, 'windowscrollWheelFcn', []);
                
                % mark trajectory beggining cross
                plot(tX(1), tY(1), 'k+')
                % mark trajectory ending bar
                plot(tX(end), tY(end), 'k|')
                hold off;
                
                % save trajectory
                savetraj(tX,tY,tZ,tV);
                % update trajectory listbox values
                updTrajIdx(app);
                % close drawing figure
                close(trjF);
                % update DRAW_STS variable
                % (drawing figure is closed)
                DRAW_STS = 0;
            end
            
            function savetraj(X, Y, Z, V)
                % get trajectories structure path
                path = 'structures/trajectories/';
                
                % get trajectory editbox text
                fname = app.EditField_2.Value;
                fpath = join([path,fname]);
                
                % editbox field is empty:
                % generic trajectory name with a 
                % timestamp is generated
                if isempty(fpath)
                    fname = join(['custom',datestr(now,'ddmmyy_HHMM_SS')]);
                    fpath = join([path,fname]);
                % custom file name
                else
                    tfile = join([fpath,'.mat']);
                    % if the file already exists:
                    % save function is interrupted
                    if isfile(tfile)
                        errormsg = sprintf('The trajectory ''%s'' already exists.',tfile);
                        uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                        clear errormsg;
                        return;
                    end
                    clear tfile;
                end
                
                % append data
                TRAJ.data.x = X';
                TRAJ.data.y = Y';
                TRAJ.data.z = Z';
                TRAJ.data.v = V';
                % append room path
                rpath = 'structures/room/';
                rfile = join([rpath,rrd]);
                TRAJ.room.path = rfile;
                % append object stats
                TRAJ.interp.sts = 0;
                TRAJ.interp.path = '';
                TRAJ.spatial.sts = 0;
                TRAJ.spatial.path = '';
                % save TRAJ structure
                save(fpath,'-struct','TRAJ');
                % CLEAN FUNCTION VARs
                clear TRAJ ROOM path fname fpath rpath rfile;
                clear rrd vel tX tY tZ;
            end
            
            function changeVel(~,event)
                % get scroll event (up/down-wards)
                SCROLLDIR = event.VerticalScrollCount;
                
                % upwards scroll = increase velocity
                if SCROLLDIR == -1
                    vel = vel + 0.02;
                % downwards scroll = decrease velocity
                else
                    % velocity should be geater than 0.02 m/s
                    if round(vel,2) > 0.02
                        vel = vel - 0.02;
                    end
                end
                
                % send velocity info to axis title
                titlemsg = sprintf('velocity = %.2f',vel);
                title(titlemsg);
                
                % CLEAN FUNCTION VARs
                clear SCROLLDIR titlemsg;
            end
            
            function error = checkSave()
                % error: 0=file does not exist
                % error: 1=file already exists
                
                % initialize error output
                error = 0;
                
                % get trajectories structure path
                path = 'structures/trajectories/';
                % get trajectory editbox text
                fname = app.EditField_2.Value;
                % get file path
                fpath = join([path,fname]);
                tfile = join([fpath,'.mat']);
                
                % editbox field: file name
                if ~isempty(fname)
                    % if the file already exists:
                    % save function is interrupted
                    if isfile(tfile)
                        errormsg = sprintf('The trajectory ''%s'' already exists.',tfile);
                        uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                        clear errormsg;
                        error = 1;
                    end
                end
                
                % CLEAN
                clear fname fpath tfile;
            end

        end

        % Value changed function: SoundsampleDropDown
        function SoundsampleDropDownValueChanged(app, event)
            % update sound sample labels
            updSndIdx(app);
        end

        % Button pushed function: STARTButton_3
        function STARTButton_3_Interpolation(app, event)
            % initialize prompt variable
            selection = 'OK';
            
            % retrieve trajectory listbox name value
            ttd = app.TrajectoriesListBox.Value;

            % no selected trajectory
            if isempty(ttd)
                uialert(app.DSTSUIFigure,'Please select a trajectory.','2D STS','Icon','error')
            % trajectory selected:
            % trajectory has been interpolated before
            elseif app.TrajectoryInterpolatedLamp.Tag == '1'
                selection = uiconfirm(app.DSTSUIFigure,'Generate new interpolation?','Warning','Icon','warning');
                interpTraj();
            % trajectory selected:
            % no interpolation -> interpolate now
            else
                interpTraj();
            end
            
            % FUNCTIONS:---------------------------------------------------
            % *************************************************************
            function interpTraj()
                if selection=="OK"
                    % retrieve trajectory file path
                    filedir = join(['structures/trajectories/',ttd]);
                    % TRAJ structure
                    TRAJ = load(filedir);
                    % ROOM structure
                    ROOM = load(TRAJ.room.path);
                    
                    % room boolean condition to check if the receiver
                    % has been implemented
                    recfail =   isempty(ROOM.rec.x) || ...
                                isempty(ROOM.rec.y) || ...
                                isempty(ROOM.rec.z);
                    
                    % receiver is not present/not been configured
                    if recfail
                        errormsg = 'Please define a receiver location.';
                        uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                        clear errormsg;
                    % proceed to interpolate trajectory data
                    else
                        % create progress bar
                        d = uiprogressdlg(  app.DSTSUIFigure,       ...
                                            'Title','Please Wait',  ...
                                            'Message','Interpolating trajectory... (? m)');
                        % progress bar at 0%
                        d.Value = 0;
                        
                        % define waypoints for waypointTrajectory
                        waypoints = [TRAJ.data.x, TRAJ.data.y, TRAJ.data.z];
                        
                        % trajectory differences
                        tt.DX = diff(TRAJ.data.x);
                        tt.DX = [0; tt.DX];
                        tt.DY = diff(TRAJ.data.y);
                        tt.DY = [0; tt.DY];
                        
                        % ...increment the progress bar to 10%
                        d.Value = 0.1;
                        
                        % trajectory distance estimation
                        tt.D = sqrt(tt.DX.^2 + tt.DY.^2);

                        % ...increment the progress bar to 20%
                        d.Value = 0.2;
                        
                        % trajectory time
                        tt.T = tt.D ./ TRAJ.data.v;
                        tt.T = cumsum(tt.T);
                        
                        % ...increment the progress bar to 30%
                        d.Value = 0.3;
                        
                        % prepare interp structure (1/2)
                        INTERP.ROOM = ROOM; % because the app allows any room to be changed at any time
                        INTERP.snd.name = app.SoundsampleDropDown.Value;
                        fval = split(app.freqLabel.Text,' ');
                        fval = fval{1};
                        INTERP.snd.f = str2double(fval)*1e3;
                        tval = split(app.timeLabel.Text,' ');
                        tval = tval{1};
                        INTERP.snd.t = str2double(tval);
                        
                        % define waypointTrajectory parameters
                        SRATE = INTERP.snd.f;
                        FRAMES = SRATE;
                        
                        % waypointTrajectory setup
                        trajectory = waypointTrajectory(waypoints,'TimeOfArrival',tt.T,'SampleRate', SRATE, 'SamplesPerFrame', FRAMES);
                        
                        % ...increment the progress bar to 60%
                        d.Value = 0.6;
                        
                        % initialize dumping arrays
                        tx = []; ty = []; tz = [];
                        % get last dump index
                        last_dump = round(round(tt.T(end))*(SRATE/FRAMES));
                        % progress bar increase per loop
                        % 35% divided into "loop" increases;
                        % 60%+35% = 95% progress bar
                        dv_inc = 0.35/last_dump;
                        
                        % initialize time estimation variables
                        tic;tm=0;tn=0;
                        
                        % start the waypointTrajectory dumping cycle
                        for i=1:last_dump
                           dump = trajectory();
                           tx = [tx; dump(:,1)];
                           ty = [ty; dump(:,2)];
                           tz = [tz; dump(:,3)];
                           
                           % tn = minutes remaining
                            ttt = toc;
                            if ttt > tm + 1
                                tm = ttt;
                                tn = round((last_dump-i)*ttt/(60*i));
                            end
                            
                            % update dlgbar
                            d.Value = d.Value + dv_inc;
                            d.Message = sprintf('Interpolating trajectory... (%d m)', tn);
                            
                        end
                        
                        % prepare interp structure (2/2)
                        INTERP.data.x = tx;
                        INTERP.data.y = ty;
                        INTERP.data.z = tz;
                        INTERP.data.v = trajectory.Velocities;
                        
                        % save interpolated/processed trajectory
                        path = 'structures/interp/';
                        fname = join(['traj_interp',datestr(now,'ddmmyy_HHMM_SS')]);
                        fpath = join([path,fname,'.mat']);
                        save(fpath,'-struct','INTERP');
                        
                        % ...increment the progress bar to 98%
                        d.Value = 0.98;
                        
                        % update TRAJ structure
                        % (update status)
                        TRAJ.interp.sts = 1;
                        TRAJ.interp.path = fpath;
                        save(join(['structures/trajectories/',app.TrajectoriesListBox.Value]), '-struct','TRAJ');
                        
                        % update lamps
                        updLamps(app);
                        
                        % ...increment the progress bar to 99%
                        d.Value = 0.99;
                        % close progress bar
                        close(d);
                        % alert/message the upon completion
                        uialert(app.DSTSUIFigure,'Trajectory interpolation complete.','2D STS','Icon','success')
                        
                        % CLEAN
                        clear TRAJ INTERP ROOM;
                        clear d waypoints tt fval tval SRATE FRAMES;
                        clear trajectory tx ty tz last_dump dv_inc;
                        clear tm tn dump ttt path fname fpath;
                    end
                end
            end
            
            % CLEAN
            clear selection ttd;
        end

        % Button pushed function: UPDATEButton
        function UPDATEButton_Trajectory(app, event)
            % update trajectory listbox values
            updTrajIdx(app);
        end

        % Button pushed function: DELETEButton
        function DELETEButton_Trajectory(app, event)
            % delete trajectory listbox value
            delTrajEntry(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroup_Room(app, event)
            % Toggle buttons reserved for the "Custom" Room
            toggleRadioBtn(app);
        end

        % Button pushed function: NEWButton_2
        function NEWButton_Room(app, event)
            % Toggle hidden fields to input new room properties
            toggleRoomEdit(app);
        end

        % Button pushed function: SAVEButton
        function SAVEButton_Room(app, event)
            % Save room
            % (this function takes care of everything)
            saveRoom(app);
        end

        % Value changed function: ListBox
        function RoomListBoxValueChanged(app, event)
            % update axis with ROOM drawing/limits (only);
            % erases the trajectory drawing
            updRoomPlot(app);
            % updates NEW room edit fields
            updRoomEditFields(app);
            % updates Receiver tab
            updReceiver(app);
        end

        % Button pushed function: UPDATEButton_2
        function UPDATEButton_Room(app, event)
            % update ROOM listbox values
            updRoomIdx(app);
        end

        % Button pushed function: DELETEButton_2
        function DELETEButton_Room(app, event)
            % delete ROOM listbox selected value
            delRoomEntry(app);
        end

        % Button pushed function: PICKANEWLOCATIONButton
        function PICKANEWLOCATIONButton_Receiver(app, event)
            % Call global variables
            global trjF;    %figure handle
            global REC_STS; %receiver status (1=drawing)
            
            % Retrieve ROOM listbox value
            rrd = app.ListBox.Value;
            
            % Room limit flag
            % (check if the point is inside the room)
            validpoint = 0;
            
            % Reset receiver status if the figure is closed
            % (way to detected if the figure is open, to avoid
            % executing the same function over and over again)
            if ~ishandle(trjF)
                REC_STS=0;
            end
            
            % Pick receiver location by mouse input
            if REC_STS==0
                % check if any ROOM is selected
                % (exit function if none is selected)
                if isempty(rrd)
                    errormsg = 'Select a room before picking a location for the receiver.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error');
                    clear errormsg;
                    return;
                end
                
                % Activate receiver status
                % (status = drawing receiver)
                REC_STS = 1;
                
                % Retrieve ROOM structure
                ROOM = load(join(['structures/room/', rrd]));
                
                % Open figure on trjF handle
                trjF = figure;
                % Draw ROOM and RECEIVER
                axes(trjF);
                loadRoomPlot(app,trjF.CurrentAxes,ROOM)
                
                % Pick receiver location within rectangular room limits
                while ~validpoint
                    % Retrieve mouse coordinates
                    [ROOM.rec.x, ROOM.rec.y] = ginput(1);
                    % Create boundary conditions
                    cx1 = ROOM.rec.x >= ROOM.xyz(1);
                    cx2 = ROOM.rec.x <= 0;
                    cy1 = ROOM.rec.y >= ROOM.xyz(2);
                    cy2 = ROOM.rec.y <= 0;
                    % Check if receiver is inside the room
                    % (and also not inside the room walls)
                    if (cx1 || cx2 || cy1 || cy2)
                        title("Invalid point!");
                    else
                        % toggle validpoint so that this infinite
                        % loop ends
                        validpoint = 1;
                        % CLEAN NESTED VARs
                        clear cx1 cx2 cy1 cy2;
                    end
                end
            % The figure is already open
            else
                errormsg = 'Finish pointing a receiver location / close the figure!';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error');
                clear errormsg;
            end
            
            % Receiver location is valid:
            % Save its location to ROOM structure
            if validpoint
                % close figure
                close(trjF);
                % update 2D location information label
                app.recLabel.Text = join(['(',num2str(round(ROOM.rec.x,2)),' ; ',num2str(round(ROOM.rec.y,2)),') m']);
                app.recLabel.BackgroundColor = [.47 .67 .19];
                % get path 
                fpath = join(['structures/room/', rrd]);
                % add missing value for z
                ROOM.rec.z = 1.7;
                % save
                save(fpath,'-struct','ROOM');
                clear ROOM validpoint fpath;
            end
            
            % update axis drawing
            updRoomPlot(app);
            
            % CLEAN
            clear rrd;
        end

        % Button pushed function: VIEWButton
        function VIEWButton_Interpolation(app, event)
            % Call global variables
            global trjF;        % drawing figure handle
            global VIEW_INTERP; % Interpolated Trajectory window flag
            
            % Retrieve trajectory listbox name value
            ttd = app.TrajectoriesListBox.Value;
            
            % Reset window flag if figure is closed
            if ~ishandle(trjF)
                VIEW_INTERP = 0;
            end
            
            % Figure is closed
            % Open new figure and draw the interpolated trajectory
            if VIEW_INTERP==0
                % No trajectory selected
                if isempty(ttd)
                    uialert(app.DSTSUIFigure,'Please select a trajectory.','2D STS','Icon','error')
                % Trajectory not interpolated
                elseif app.TrajectoryInterpolatedLamp.Tag == '0'
                    uialert(app.DSTSUIFigure,'This trajectory has not been interpolated.','2D STS','Icon','error')
                % Trajectory interpolated
                else
                    % Activate the figure variable
                    VIEW_INTERP=1;
                    % Open a new figure
                    trjF = figure;
                    axes(trjF);
                    % TRAJ structure
                    TRAJ = load(join(['structures/trajectories/',ttd]));
                    % INTERP structure
                    INTERP = load(TRAJ.interp.path);
                    % Plot Room data
                    loadRoomPlot(app, trjF.CurrentAxes, INTERP.ROOM);
                    % Plot Interpolated trajectory data
                    hold(trjF.CurrentAxes,'on')
                    plot(INTERP.data.x, INTERP.data.y,'b')
                    hold(trjF.CurrentAxes,'off')
                    % Figure title (w/ the sample name)
                    title(join(['Sample: ', INTERP.snd.name]),'Interpreter','none')
                    
                    % CLEAN NESTED VARs
                    clear TRAJ INTERP;
                end
            % Figure is already open
            else
                uialert(app.DSTSUIFigure,'A plot figure is already open!','2D STS','Icon','error')
            end
            
            % CLEAN
            clear ttd;
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_Spatial(app, event)
            % load TRAJ list value
            ttd = app.TrajectoriesListBox.Value;
            
            % initialize selection variable
            selection = "OK";
            
            % throw warning if no trajectory was selected
            if isempty(ttd)
                errormsg = 'Please select a trajectory.';
                uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                clear errormsg ttd;
            else
            % trajectory is selected
                % load TRAJ structure
                TRAJ = load(join(['structures/trajectories/',ttd]));
                
                % trajectory not interpolated
                if      TRAJ.interp.sts==0
                    errormsg = 'Trajectory has not been interpolated.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error')
                    clear errormsg ttd;
                % trajectory has already been reconstructed
                % (however may not be reconstructed in the same ROOM
                % environmental conditions)
                elseif  TRAJ.spatial.sts==1
                    selection = uiconfirm(app.DSTSUIFigure,'Reconstruct new spatial sound?','Warning','Icon','warning');
                    recsound();
                % trajectory is interpolated and ready to be reconstructed
                else
                    recsound();
                end 
            end
            
            
            % FUNCTIONS:---------------------------------------------------
            % *************************************************************
            function recsound()
                % user agreed upon reconstructing the sound
                if selection == "OK"
                    % progress bar initialization
                    d = uiprogressdlg(  app.DSTSUIFigure,       ...
                                        'Title','Please Wait',  ...
                                        'Message','Reconstructing spatial sound... (?)');
                    
                    % set progressbar value to 0%
                    d.Value = 0;
                    
                    % load interpolated trajectory
                    % (INTERP structure)
                    INTERP = load(TRAJ.interp.path);
                    N = length(INTERP.data.x);
                    
                    % ROOM structure
                    % (from interpolated trajectory)
                    ROOM = INTERP.ROOM;
                    
                    % load audio
                    [s, fs] = audioread(join(['soundfiles/generic/',INTERP.snd.name]));
                    % set progressbar value to 10%
                    d.Value = 0.1;
                    
                    % initialize stereo channels
                    y = zeros(2,N);
                    
                    % check if sound samples do not cover trajectory
                    % (if they don't: repeat the samples until the length
                    % of the array is the same length of the interpolated
                    % trajectory array)
                    if N > length(s)
                        s = repmat(s, ceil(N/length(s)), 1);
                    end
                    
                    % frequency limits
                    fl = 100;   % lower frequency bound
                    fh = 20e3;  % upper frequency bound (<=80 kHz due to @KemoL10_TF)
                    
                    % generate division
                    AW = addDivision(ROOM.xyz, ROOM.rc);
                    
                    % generate head/receiver
                    rec_ = Receiver('structures/recstruct');
                    AM = addReceiver0(  rec_,                           ...
                                        [ROOM.rec.x ROOM.rec.y ROOM.rec.z],    ...
                                        [ROOM.rec.th ROOM.rec.phi]);
                    
                    % speaker/source initial location
                    S = addSpk([INTERP.data.x(1) INTERP.data.y(1) INTERP.data.z(1)]);
                    
                    % room properties
                    R=      Room();
                    R.T=    ROOM.t;
                    R.H=    ROOM.h;
                    R.P=    ROOM.p;
                    
                    % Maximum reflections
                    MR = 0;
                    
                    % MAKE divison object
                    makeFile('app_div_obj', AW, AM, MR, fs, fl, fh);
                    % set progressbar value to 20%
                    d.Value = 0.2;
                    
                    % generate progressbar increment values
                    d_inc = 0.75/N;
                    % time variables
                    tm = 0; tn=0;
                    % start timer/clock
                    clk = tic;
                    
                    % RECONSTRUCTION LOOP__________________________________
                    for n=1:N
                        % skip on: out-of-boundaries coordinates
                        if INTERP.data.x(n) <= 0
                            continue
                        elseif INTERP.data.y(n) <= 0
                            continue
                        else
                            if INTERP.data.z(n) <= 0
                                continue
                            end
                        end
                        
                        % feed data to speaker/moving object
                        S.C(1) = INTERP.data.x(n);
                        S.C(2) = INTERP.data.y(n);
                        S.C(3) = INTERP.data.z(n);
                        
                        % impulse responses
                        I = impR('app_div_obj', S, R);
                        M = size(I,2); 
                        
                        % when samples outside of signal y
                        % are not needed -
                        % (assuming that they are null values)
                        % - those samples are removed from
                        % the impulse response I
                        if n+M-1>N
                            M = N+1-n;
                            I(:,M+1:end) = [];
                        end
                        y(:,n:n+M-1) = y(:,n:n+M-1)+s(n).*I;
                        
                        % tn = remaining minutes
                        ttt = toc(clk);
                        if ttt > tm + 1
                            tm = ttt;
                            tn = round((N-n)*ttt/(60*n));
                        end
                        
                        % increase the progressbar 
                        % percentage by an increment
                        d.Value = d.Value + d_inc;
                        % update progress bar message
                        d.Message = sprintf('Reconstructing spatial sound... (%d m)', tn);
                    end
                    
                    % write stereo sample
                    % (same name as the trajectory)
                    path = 'soundfiles/generated/';
                    fname = split(ttd,'.mat');
                    fname = fname{1};
                    fpath = join([path,fname,'.wav']);
                    audiowrite(fpath,y',fs)
                    
                    % update TRAJ file
                    TRAJ.spatial.sts = 1;
                    TRAJ.spatial.path = fpath;
                    save(join(['structures/trajectories/',ttd]), '-struct','TRAJ');
                    
                    % set progressbar value to 99%
                    d.Value = 0.99;
                    
                    % update app Lamps status
                    updLamps(app);
                    
                    % alert the user that the spatial reconstruction
                    % is finished
                    uialert(app.DSTSUIFigure,'Spatial sound has been reconstructed.','2D STS','Icon','success')
                    
                    % CLEAN
                    clear TRAJ ROOM INTERP N;
                    clear R S AW AM MR I M;
                    clear s fs y fl fh rec_;
                    clear d d_inc tm tn ttt clk;
                    clear path fname fpath ttd;
                    delete('app_div_obj.mat');
                else
                    % user did not agree to proceed with
                    % spatial sound reconstruction
                    
                    % CLEAN
                    clean ttd;
                end
            end
        end

        % Button pushed function: DEMOButton
        function DEMOButton_Spatial(app, event)
            % call global variables
            global trjF;            % drawing figure handle
            global VIEW_SPATIAL;    % Spatial Demo window flag
            
            % Reset window flag if figure is closed
            if ~ishandle(trjF)
                VIEW_SPATIAL = 0;
            end
            
            % load TRAJ list value
            ttd = app.TrajectoriesListBox.Value;
            
            % spatial sound window is closed
            if VIEW_SPATIAL == 0
                % no trajectory has been selected
                % (exit function and throw warning)
                if isempty(ttd)
                    errormsg = 'Please select a trajectory.';
                    uialert(app.DSTSUIFigure,errormsg,'2D STS','Icon','error');
                    clear errormsg ttd;
                    return;
                end
                
                % TRAJ structure
                TRAJ = load(join(['structures/trajectories/',ttd]));
                
                % Spatial sound reconstruction
                % not present for this trajectory
                % (exit function and throw warning)
                if  TRAJ.spatial.sts == 0
                    alertmsg = sprintf('No spatial sound available for trajectory %s.', ttd);
                    uialert(app.DSTSUIFigure,alertmsg,'2D STS','Icon','error')
                    clear alertmsg ttd TRAJ;
                    return;
                end
                
                % Activate spatial sound window flag
                VIEW_SPATIAL = 1;
                
                % interpolated trajectory structure
                INTERP = load(TRAJ.interp.path);
                % ROOM structure saved in INTERP
                ROOM = INTERP.ROOM;
                
                % Open figure
                trjF = figure;
                axes(trjF);
                % Draw room
                loadRoomPlot(app, trjF.CurrentAxes, ROOM);
                % Adjust figure and axis
                trjF.ToolBar = 'none';
                trjF.MenuBar = 'none';
                trjF.Resize = 'off';
                trjF.OuterPosition = [100 100 1280 720];
                % shadow region
                % (on hold)
                
                % Draw trajectory
                hold on
                plot(INTERP.data.x(1), INTERP.data.y(1),     ...
                    'b+', 'LineWidth', 2)
                plot(INTERP.data.x, INTERP.data.y,           ...
                    'b+', 'LineWidth', 1)
                plot(INTERP.data.x(end), INTERP.data.y(end), ...
                    'b+', 'LineWidth', 2)
                hold off
                
                % Draw azimuth detection line   
                hold on;
                DETx = [ROOM.rec.x []];
                DETy = [ROOM.rec.y []];
                DETp = plot(DETx, DETy, 'r');
                DETp.XDataSource = 'DETx';
                DETp.YDataSource = 'DETy';
                hold off;
                
                % Draw theoretical azimuth dot
                hold on;
                REALx = INTERP.data.x(1);
                REALy = INTERP.data.y(1);
                REALp = plot(REALx, REALy, 'k*', 'LineWidth', 2);
                REALp.XDataSource = 'REALx';
                REALp.YDataSource = 'REALy';
                hold off;
                
                % Update figure and axes
                title('Please wait...');
                drawnow
                
                
                % REAL TIME SETUP AND EXECUTION
                %________________________________
                % sound sample
                snd = TRAJ.spatial.path;
                       
                % load mosquito sample
                [~, fs] = audioread(snd);
                
                % block settings
                tBLK = 100e-3;      % 100ms for each block
                szBLK = tBLK * fs;  % block samples for 100ms
                
                % distance for detection (line across the room)
                d = sqrt(ROOM.xyz(1).^2+ROOM.xyz(2).^2);
                
                % AudioDeviceWriter -- init
                fileReader = dsp.AudioFileReader(snd);
                fileReader.SamplesPerFrame = szBLK;
                fileInfo = audioinfo(snd);
                deviceWriter = audioDeviceWriter('SampleRate',fileInfo.SampleRate);
                setup(deviceWriter,zeros(fileReader.SamplesPerFrame,fileInfo.NumChannels))
                info(deviceWriter);
                title('');
                
                % Prepare variables for detection algorithm 
                CH.fs = fileInfo.SampleRate;    % samplerate
                D_X = 0.2;                      % microphone spacing
                CR = round(D_X + 1e-3*CH.fs);   % correlation range
                C = 20.05*sqrt(273.15+ROOM.t);  % speed of sound

                
                % AudioDeviceWriter -- loop
                i=1;
                while ~isDone(fileReader) % go through all blocks
                    % check if window has been closed
                    % if closed: get out of loop
                    if ~isvalid(trjF)
                        break;
                    end
                    
                    % Retrieve sound samples from each channel
                    audioData = fileReader();
                    CH.L = audioData(:,1);
                    CH.R = audioData(:,2);
                    
                    % Apply azimuth detection algorithm
%                     AOA = detect_az1(CH,CR,C,D_X);
                    [AOA,~] = detect_az3(CH,CR,C,D_X);
                
                    % Update azimuth detection line data
                    DETx = [ROOM.rec.x+d*cosd(AOA-90) ROOM.rec.x];
                    DETy = [ROOM.rec.y+d*sind(AOA-90) ROOM.rec.y];
                    
                    % Update theoretical azimuth dot values
                    REALx = INTERP.data.x(i);
                    REALy = INTERP.data.y(i);
                    
                    % Advance through enough samples
                    % that are equivalent to one block of 100ms
                    i=i+((CH.fs*tBLK)-1);
                    
                    % Real time plot data update
                    refreshdata(trjF,'caller');
                    drawnow;
                    % Send previous block to AudioDeviceWriter
                    deviceWriter(audioData);
                end
                
                % AudioDeviceWriter -- close
                release(fileReader);
                release(deviceWriter);
                
                % CLEAN NESTED VARs
                clear TRAJ INTERP ROOM;
                clear DETx DETy DETp;
                clear REALx REALy REALp;
                clear snd fs tBLK szBLK d;
                clear fileReader fileInfo deviceWriter;
                clear CH D_X CR C;
                clear i audioData AOA;
            end
            
            % CLEAN
            clear ttd;
        end

        % Value changing function: AzimuthKnob
        function AzimuthKnobValueChanging(app, event)
            % Event changing value
            changingValue = event.Value;
            % Update azimuth value label
            app.azvalueLabel.Text = num2str(round(changingValue));
        end

        % Value changed function: AzimuthKnob
        function AzimuthKnobValueChanged(app, event)
            % ROOM ListBox name value
            rrd = app.ListBox.Value;
            % save azimuth value if the selected value/ROOM is valid
            if ~isempty(rrd)
                % ROOM structure
                ROOM = load(join(['structures/room/',rrd]));
                % save value from azimuth knob into ROOM structure
                ROOM.rec.th = app.AzimuthKnob.Value;
                % save structure to directory
                save(join(['structures/room/',rrd]),'-struct','ROOM');
                % update axis drawing
                updRoomPlot(app);
                % CLEAN NESTED VARs
                clear ROOM
            end
            % CLEAN
            clear rrd;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSTSUIFigure = uifigure('Visible', 'off');
            app.DSTSUIFigure.Name = '2D STS';
            app.UIAxes = uiaxes(app.DSTSUIFigure);
            app.TabGroup = uitabgroup(app.DSTSUIFigure);
            app.RoomTab = uitab(app.TabGroup);
            app.RoomTab.Title = 'Room';
            app.ListBox = uilistbox(app.RoomTab);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @RoomListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ButtonGroup = uibuttongroup(app.RoomTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Room, true);
            app.RectangularButton = uiradiobutton(app.ButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.CustomButton = uiradiobutton(app.ButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.NEWButton_2 = uibutton(app.RoomTab, 'push');
            app.NEWButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEWButton_Room, true);
            app.NEWButton_2.Text = 'NEW';
            app.UPDATEButton_2 = uibutton(app.RoomTab, 'push');
            app.UPDATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButton_Room, true);
            app.UPDATEButton_2.Text = 'UPDATE';
            app.DELETEButton_2 = uibutton(app.RoomTab, 'push');
            app.DELETEButton_2.ButtonPushedFcn = createCallbackFcn(app, @DELETEButton_Room, true);
            app.DELETEButton_2.Text = 'DELETE';
            app.SAVEButton = uibutton(app.RoomTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_Room, true);
            app.SAVEButton.Text = 'SAVE';
            app.YEditFieldLabel = uilabel(app.RoomTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RoomTab, 'text');
            app.XEditFieldLabel = uilabel(app.RoomTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RoomTab, 'text');
            app.EditField = uieditfield(app.RoomTab, 'text');
            app.TemperatureEditField = uieditfield(app.RoomTab, 'numeric');
            app.TemperatureEditField.Value = 20;
            app.HumidityEditField = uieditfield(app.RoomTab, 'numeric');
            app.HumidityEditField.Value = 50;
            app.PressureEditField = uieditfield(app.RoomTab, 'numeric');
            app.PressureEditField.Value = 1.01;
            app.TemperatureLabel = uilabel(app.RoomTab);
            app.TemperatureLabel.Text = '🌡 (ºC)';
            app.HumidityLabel = uilabel(app.RoomTab);
            app.HumidityLabel.Text = '💧 (%)';
            app.PressureLabel = uilabel(app.RoomTab);
            app.PressureLabel.Text = '⏲ (atm)';
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Title = 'Receiver';
            app.AzimuthKnob = uiknob(app.ReceiverTab, 'continuous');
            app.AzimuthKnob.Limits = [-180 180];
            app.AzimuthKnob.ValueChangedFcn = createCallbackFcn(app, @AzimuthKnobValueChanged, true);
            app.AzimuthKnob.ValueChangingFcn = createCallbackFcn(app, @AzimuthKnobValueChanging, true);
            app.AzimuthKnobLabel = uilabel(app.ReceiverTab);
            app.AzimuthKnobLabel.Text = 'Azimuth';
            app.azvalueLabel = uilabel(app.ReceiverTab);
            app.azvalueLabel.Text = 'azvalue';
            app.PICKANEWLOCATIONButton = uibutton(app.ReceiverTab, 'push');
            app.PICKANEWLOCATIONButton.ButtonPushedFcn = createCallbackFcn(app, @PICKANEWLOCATIONButton_Receiver, true);
            app.PICKANEWLOCATIONButton.Text = 'PICK A NEW LOCATION';
            app.recLabel = uilabel(app.ReceiverTab);
            app.recLabel.Text = 'rec';
            app.SourceTab = uitab(app.TabGroup);
            app.SourceTab.Title = 'Source';
            app.TrajectoriesListBox = uilistbox(app.SourceTab);
            app.TrajectoriesListBox.Items = {};
            app.TrajectoriesListBox.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesListBoxValueChanged, true);
            app.TrajectoriesListBox.Value = {};
            app.NEWButton = uibutton(app.SourceTab, 'push');
            app.NEWButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWButton_Trajectory, true);
            app.NEWButton.Text = 'NEW';
            app.SoundsampleDropDown = uidropdown(app.SourceTab);
            app.SoundsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundsampleDropDownValueChanged, true);
            app.freqLabel = uilabel(app.SourceTab);
            app.freqLabel.Text = 'freq';
            app.timeLabel = uilabel(app.SourceTab);
            app.timeLabel.Text = 'time';
            app.UPDATEButton = uibutton(app.SourceTab, 'push');
            app.UPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButton_Trajectory, true);
            app.UPDATEButton.Text = 'UPDATE';
            app.DELETEButton = uibutton(app.SourceTab, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButton_Trajectory, true);
            app.DELETEButton.Text = 'DELETE';
            app.SOUNDLabel = uilabel(app.SourceTab);
            app.SOUNDLabel.Text = '2. SOUND';
            app.TRAJECTORYLabel = uilabel(app.SourceTab);
            app.TRAJECTORYLabel.Text = '1. TRAJECTORY';
            app.EditField_2 = uieditfield(app.SourceTab, 'text');
            app.STARTButton_2 = uibutton(app.DSTSUIFigure, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_Spatial, true);
            app.STARTButton_2.Text = 'START';
            app.DEMOButton = uibutton(app.DSTSUIFigure, 'push');
            app.DEMOButton.ButtonPushedFcn = createCallbackFcn(app, @DEMOButton_Spatial, true);
            app.DEMOButton.Text = 'DEMO';
            app.SpatialSoundSimulationLabel = uilabel(app.DSTSUIFigure);
            app.SpatialSoundSimulationLabel.Text = 'Spatial Sound Simulation';
            app.SpatialSoundSimulationLamp = uilamp(app.DSTSUIFigure);
            app.STARTButton_3 = uibutton(app.DSTSUIFigure, 'push');
            app.STARTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_3_Interpolation, true);
            app.STARTButton_3.Text = 'START';
            app.VIEWButton = uibutton(app.DSTSUIFigure, 'push');
            app.VIEWButton.ButtonPushedFcn = createCallbackFcn(app, @VIEWButton_Interpolation, true);
            app.VIEWButton.Text = 'VIEW';
            app.TrajectoryInterpolatedLabel = uilabel(app.DSTSUIFigure);
            app.TrajectoryInterpolatedLabel.Text = 'Trajectory Interpolated';
            app.TrajectoryInterpolatedLamp = uilamp(app.DSTSUIFigure);
            % Show the figure after all components are created
            app.DSTSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simsound

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSTSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSTSUIFigure)
        end
    end
end